classdef LeafMachineBatchGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        axes1             matlab.ui.control.UIAxes
        uibuttongroup1    matlab.ui.container.ButtonGroup
        saveFolderSuffix  matlab.ui.control.EditField
        text6             matlab.ui.control.Label
        changeBatchSave   matlab.ui.control.Button
        saveLocationText  matlab.ui.control.Label
        uibuttongroup3    matlab.ui.container.ButtonGroup
        changeBatchOpen   matlab.ui.control.Button
        openLocationText  matlab.ui.control.Label
        Run               matlab.ui.control.Button
        Stop              matlab.ui.control.Button
        fileSaveDisp      matlab.ui.control.Label
        progress          matlab.ui.control.Label
        uibuttongroup5    matlab.ui.container.ButtonGroup
        radiobuttonLocal  matlab.ui.control.RadioButton
        radiobuttonURL    matlab.ui.control.RadioButton
        text13            matlab.ui.control.Label
        progress2         matlab.ui.control.Label
        uibuttongroup4    matlab.ui.container.ButtonGroup
        urlColumn         matlab.ui.control.EditField
        text10            matlab.ui.control.Label
        changeURL         matlab.ui.control.Button
        urlText           matlab.ui.control.Label
        changeURL2        matlab.ui.control.Button
        urlText2          matlab.ui.control.Label
        uibuttongroup6    matlab.ui.container.ButtonGroup
        dwc_high          matlab.ui.control.RadioButton
        dwc_low           matlab.ui.control.RadioButton
        text15            matlab.ui.control.Label
        uipanel1          matlab.ui.container.Panel
        checkboxGPU       matlab.ui.control.CheckBox
        allFilesSuffix    matlab.ui.control.EditField
        text8             matlab.ui.control.Label
        quality           matlab.ui.control.CheckBox
        checkboxLCM       matlab.ui.control.CheckBox
        checkboxLS        matlab.ui.control.CheckBox
        checkboxIND       matlab.ui.control.CheckBox
        inputSaveFreq     matlab.ui.control.EditField
        text18            matlab.ui.control.Label
        imfillMasksCB     matlab.ui.control.CheckBox
        uipanel4          matlab.ui.container.Panel
        uipanel2          matlab.ui.container.Panel
        text24            matlab.ui.control.Label
        Dimages           matlab.ui.control.Button
        Docc              matlab.ui.control.Button
        Drun              matlab.ui.control.Button
        uipanel8          matlab.ui.container.Panel
        filterGroup       matlab.ui.container.ButtonGroup
        FGfamily          matlab.ui.control.RadioButton
        FGgenera          matlab.ui.control.RadioButton
        FGspecies         matlab.ui.control.RadioButton
        text23            matlab.ui.control.Label
        FGset             matlab.ui.control.Button
        uipanel6          matlab.ui.container.Panel
        customSet         matlab.ui.control.Button
        customOut         matlab.ui.control.Button
        text22            matlab.ui.control.Label
        customRun         matlab.ui.control.Button
        text25            matlab.ui.control.Label
    end

    
    methods (Access = private)
        function ArrowCursor(app)
                set(gcf, 'pointer', 'arrow')
                drawnow;
        end
        
        function WaitCursor(app)
                set(gcf, 'pointer', 'watch')
                drawnow;
        end
        
        function uibuttongroup6_CreateFcn(app, hObject, eventdata, handles)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end
        
        function uibuttongroup6_SelectionChangedFcn(app, hObject, eventdata, handles)
                handles = guidata(hObject);
            
                set(app.uibuttongroup6,'SelectionChangeFcn',@uibuttongroup6_SelectionChangedFcn)
                switch(get(eventdata.NewValue,'Tag'))
                    case 'dwc_high'
                        app.url_col_radio = "goodQualityAccessURI";
                    case 'dwc_low'
                        app.url_col_radio = "accessURI";
                end
                guidata(hObject,handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LeafMachineBatchGUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to LeafMachineBatchGUI (see VARARGIN)
%             
%             % Choose default command line output for LeafMachineBatchGUI
%                 app.output = hObject;
%                 format short g
%             
%                 % Check GPU status
%                 try
%                     gpuDevice;
%                     app.GPUstatus = 'TRUE';
%                     sprintf("GPU Available")
%                 catch
%                     app.GPUstatus = 'FALSE';
%                     sprintf("GPU Not Available")
%                 end
%             
%                 % Add to path
%                 addpath(genpath('Img'));
%                 addpath(genpath('Leaf_Data'));
%                 addpath(genpath('Processed_Images'));
%                 addpath(genpath('Raw_Images'));
%                 addpath(genpath('Ruler'));
%                 addpath(genpath('Networks'));
%                 addpath(genpath('SandboxFunctions'));
%                 addpath(genpath('Training_Images'));
%             
%                 try
%                     nnet.internal.cnngpu.reluForward(gpuArray(0));
%                 catch
%                     nnet.internal.cnngpu.reluForward(gpuArray(0));
%                 end
%             
%                 % Placeholder Images
%             %     app.S = load('Networks/LeafMachine_SegNet_v1.mat');
%             %     app.LeafMachine_SegNet_v1 = app.S.LeafMachine_SegNet_v1;
%             %     app.SVM = load('Networks/SVM/leafID_SVM_8parameters.mat');
%             %     app.SVM = app.SVM.leafID_SVM_8parameters;
%             
%                 app.SVM = load('Networks/SVM/leafID_subset20AdaBoost50splits100learners0810percent.mat');
%                 app.SVM = app.SVM.leafID_subset20AdaBoost50splits100learners0810percent;
%             %     app.SVM = load('Networks/SVM/leafID_AdaBoost500splits100learners8var0799percent.mat');
%             %     app.SVM = app.SVM.leafID_AdaBoost500splits100learners8var0799percent;
%                 app.netSVMruler = load('Networks/SVM/SVM_RulerID_BaggedTrees0906percent.mat');
%                 app.netSVMruler = app.netSVMruler.SVM_RulerID_BaggedTrees0906percent;
%                 app.S = load('Networks/deeplabV3Plus_Lexi_dynamicCrop_MWK_network.mat');
%                 app.LeafMachine_SegNet_v1 = app.S.deeplabV3Plus_Lexi_dynamicCrop_MWK;
%             %     app.SVM = load('Networks/SVM/baggedTrees_937_Compact.mat');
%             %     app.SVM = app.SVM.baggedTrees_937_Compact;
%             
%                 app.allPlantFamilies = load('SandboxFunctions/allPlantFamilies.mat');
%             
%                 app.placeholder = imread('Img/LMlarge.jpg');
%                 app.placeholder2 = imread('Img/Batch.jpg');
%                 %app.newData = buildData()
%                 axes(app.axes1);
%                 imshow(app.placeholder);
%                 uibuttongroup5_CreateFcn(hObject, eventdata, handles);
%                 set(app.uibuttongroup5,'selectedobject',app.radiobuttonLocal);
%                 set(app.uibuttongroup6,'selectedobject',app.dwc_high);
%                 app.url_col_radio = "NA";
%                 app.dirFGFile = "noFilter";
%             
%                 guidata(hObject, handles);
        end

        % Button pushed function: Dimages
        function Dimages_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Button pushed function: Docc
        function Docc_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Button pushed function: Drun
        function Drun_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Button pushed function: FGset
        function FGset_Callback(app, event)
                handles = guidata(hObject);
                % Choose file
                [app.dirFGFileName,app.dirFGPath] = uigetfile({'*.csv'},'Choose .csv File Containing URLs in a Column');
                addpath(app.dirFGPath);
                app.dirFGFile = fullfile(app.dirFGPath,app.dirFGFileName);
            
                % Update GUI
                set(app.FGset,'BackgroundColor',[.47 .67 .19]);
            
                guidata(hObject, handles);
        end

        % Button pushed function: Run
        function Run_Callback(app, event)
                handles = guidata(hObject);
            
                set(app.uibuttongroup5,'SelectionChangeFcn',@uibuttongroup5_SelectionChangedFcn)
                % Check radiobuttons for local_URL status
                if app.radiobuttonLocal.Value
                    app.local_url = 'local';
                    app.dirOpen2 = 'NA';
                else
                    app.local_url = 'url';
                end
            
                set(app.uibuttongroup6,'SelectionChangeFcn',@uibuttongroup6_SelectionChangedFcn)
                % Check radiobuttons for local_URL status
                if app.dwc_high.Value
                    app.url_col_radio = "accessURI";
                else
                    app.url_col_radio = "goodQualityAccessURI";
                end
            
            
                %%% Filter Group Module
                set(app.filterGroup,'SelectionChangeFcn',@filterGroup_SelectionChangedFcn)
                % Check radiobuttons for Filter Group
                if app.dirFGFile == "noFilter"
                    app.FGfilter = "noFilter";
                else
                    if app.FGfamily.Value
                        app.FGfilter = "family";
                    elseif app.FGgenera.Value
                        app.FGfilter = "genera";
                    elseif app.FGspecies.Value
                        app.FGfilter = "species";
                    else
                        app.FGfilter = "noFilter";
                    end
                end
            
            
                % If user did not set open dir prior to run
                if app.radiobuttonLocal.Value == 0
                else
                    if app.openLocationText.String == "Location not set..."
                        % Choose folder location
                        [app.dirOpen] = uigetdir('Choose Folder for Batch Processing');
                        addpath(app.dirOpen)
                        % Parse folder name
                        fOut = strsplit(app.dirOpen,'\');
                        fOut = fOut(length(fOut));
                        app.dirOpenName = char(fOut(1));
                        % Update GUI
                        set(app.openLocationText,'string',strcat('Opening From > ',char(app.dirOpenName)),'ForegroundColor',[.47 .67 .19]);
                        set(app.changeBatchOpen,'BackgroundColor',[.47 .67 .19]);
                        guidata(hObject,handles);
                    end
                end
                % If user did not set save dir prior to run
                if app.saveLocationText.String == "Location not set..."
                    % Choose folder location
                    [app.dirSave] = uigetdir('Choose Save Location');
                    addpath(app.dirSave)
            
                    % Parse folder name
                    fOut = strsplit(app.dirSave,'\');
                    fOut = fOut(length(fOut));
                    app.dirSaveName = char(fOut(1));
                    app.saveSuffix = get(app.saveFolderSuffix,'String');
                    if app.saveSuffix == ""
                        app.dirSave_wSuffix = app.dirSave;
                    else
                        app.dirSave_wSuffix = fullfile(app.dirSave,app.saveSuffix);
                    end
                    % Savdir to display in console
                    app.dirSaveName_wSuffix = strcat(app.dirSaveName,'>',app.saveSuffix);
            
                    % Update GUI
                    set(app.saveLocationText,'string',strcat('Destination Set > ',char(app.dirSaveName_wSuffix)),'ForegroundColor',[.47 .67 .19]);
                    set(app.changeBatchSave,'BackgroundColor',[.47 .67 .19]);
                    guidata(hObject,handles);
                end
                % Check checkboxes for gpu status
                if app.checkboxGPU.Value
                    app.gpu_cpu = 'gpu';
                else
                    app.gpu_cpu = 'cpu';
                end
            
                % Check checkboxes for LazySnapping status
                if app.checkboxLS.Value
                    app.LS = true;
                else
                    app.LS = false;
                end
            
                % Check checkboxes for saveLeafCandidateMask status
                if app.checkboxLCM.Value
                    app.LCM = true;
                else
                    app.LCM = false;
                end
            
                % Check checkboxes for image quality
                if app.quality.Value
                    app.quality = 'High';
                else
                    app.quality = 'Low';
                end
            
                % Check checkboxes for image quality
                if app.checkboxIND.Value
                    app.checkboxIND = true;
                else
                    app.checkboxIND = false;
                end
            
                % Check checkboxes for image quality
                if app.imfillMasksCB.Value
                    app.imfillMasksCB = "True";
                else
                    app.imfillMasksCB = "False";
                end
            
                % Get save freq
                defaultSave = 10;
                app.saveFreq = round(str2double(get(app.inputSaveFreq,'String')));
            
                if isempty(app.saveFreq)
                    app.saveFreq = uint64(defaultSave);
                else
                    try
                        app.saveFreq = uint64(app.saveFreq);
                    catch
                        app.saveFreq = uint64(defaultSave);
                    end
                end
            
            
                % Check checkboxes for allFilesSuffix status
                app.filesSuffix = get(app.allFilesSuffix,'String');
                app.url_col = get(app.urlColumn,'String');
            
                if isempty(app.url_col)
                    app.url_col = app.url_col_radio;
                end
            
                %{Leaf,Background,Stem,Text_Black,Fruit_Flower};
                app.feature = 1;%Leaf
                feature = app.feature;
            
                WaitCursor();
            
                % Run Operations!!!
                %[nFiles,BatchTime] = LeafMachineBatchSegmentation(app.dirOpen,app.segOpts,app.LeafMachine_SegNet_v1,5,app.gpu_cpu,app.visOpts,app.filesSuffix,app.dirSave_wSuffix,handles)
                [nFiles,BatchTime] = LeafMachineBatchSegmentation_GUI(app.dirOpen,app.dirOpen2,app.LeafMachine_SegNet_v1,app.SVM,app.netSVMruler,app.LCM,app.LS,app.checkboxIND,app.saveFreq,feature,app.gpu_cpu,...
                                    app.local_url,app.url_col,app.imfillMasksCB,app.quality,app.filesSuffix,app.dirSave_wSuffix,handles,hObject)
                % GUI
                ArrowCursor();
                set(app.fileSaveDisp,'String',strcat(nFiles," Images Processed in ",BatchTime," Seconds"),'ForegroundColor',[0 .45 .74]);
            
                guidata(hObject,handles);
        end

        % Button pushed function: Stop
        function Stop_Callback(app, event)
                closereq
        end

        % Value changed function: allFilesSuffix
        function allFilesSuffix_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Button pushed function: changeBatchOpen
        function changeBatchOpen_Callback(app, event)
                handles = guidata(hObject);
                try
                    % Choose folder location
                    [app.dirOpen] = uigetdir('Choose Folder for Batch Processing');
                    addpath(app.dirOpen)
            
                    % Parse folder name
                    fOut = strsplit(app.dirOpen,'\');
                    fOut = fOut(length(fOut));
                    app.dirOpenName = char(fOut(1));
            
                    % Update GUI
                    set(app.openLocationText,'string',strcat('Opening From > ',char(app.dirOpenName)),'ForegroundColor',[.47 .67 .19]);
                    set(app.changeBatchOpen,'BackgroundColor',[.47 .67 .19]);
                    set(app.fileSaveDisp,'String',"");
                catch
                end
                guidata(hObject, handles);
        end

        % Button pushed function: changeBatchSave
        function changeBatchSave_Callback(app, event)
                handles = guidata(hObject);
                try
                    % Choose folder location
                    [app.dirSave] = uigetdir('Choose Save Location');
                    addpath(app.dirSave)
            
                    % Parse folder name
                    fOut = strsplit(app.dirSave,'\');
                    fOut = fOut(length(fOut));
                    app.dirSaveName = char(fOut(1));
                    app.saveSuffix = get(app.saveFolderSuffix,'String');
                    if app.saveSuffix == ""
                        app.dirSave_wSuffix = app.dirSave;
                    else
                        app.dirSave_wSuffix = fullfile(app.dirSave,app.saveSuffix);
                    end
                    % Savdir to display in console
                    app.dirSaveName_wSuffix = strcat(app.dirSaveName,' > ',app.saveSuffix);
            
                    % Update GUI
                    set(app.saveLocationText,'string',strcat('Destination Set > ',char(app.dirSaveName_wSuffix)),'ForegroundColor',[.47 .67 .19]);
                    set(app.changeBatchSave,'BackgroundColor',[.47 .67 .19]);
                catch
                end
                guidata(hObject, handles);
        end

        % Button pushed function: changeURL2
        function changeURL2_Callback(app, event)
                handles = guidata(hObject);
                [app.dirURLFileName2,app.dirURLPath2] = uigetfile({'*.csv'},'Choose .csv File Containing URLs in a Column');
                addpath(app.dirURLPath2);
                app.dirURLFile2 = fullfile(app.dirURLPath2,app.dirURLFileName2);
                app.dirOpen2 = app.dirURLFile2;
            
                % Update GUI
                set(app.urlText2,'string',strcat('Opening From > ',char(app.dirURLFileName2)),'ForegroundColor',[.47 .67 .19]);
                set(app.changeURL2,'BackgroundColor',[.47 .67 .19]);
                set(app.fileSaveDisp,'String',"");
                set(app.uibuttongroup5,'selectedobject',app.radiobuttonURL);
            
                guidata(hObject, handles);
        end

        % Button pushed function: changeURL
        function changeURL_Callback(app, event)
                handles = guidata(hObject);
                % Choose file
                [app.dirURLFileName,app.dirURLPath] = uigetfile({'*.csv'},'Choose .csv File Containing URLs in a Column');
                addpath(app.dirURLPath);
                app.dirURLFile = fullfile(app.dirURLPath,app.dirURLFileName);
                app.dirOpen = app.dirURLFile;
            
                % Update GUI
                set(app.urlText,'string',strcat('Opening From > ',char(app.dirURLFileName)),'ForegroundColor',[.47 .67 .19]);
                set(app.changeURL,'BackgroundColor',[.47 .67 .19]);
                set(app.fileSaveDisp,'String',"");
                set(app.uibuttongroup5,'selectedobject',app.radiobuttonURL);
            
                guidata(hObject, handles);
        end

        % Button pushed function: customOut
        function customOut_Callback(app, event)
                handles = guidata(hObject);
                try
                    % Choose folder location
                    [app.dirCustomOut] = uigetdir('Choose Save Location');
                    addpath(app.dirCustomOut)
            
                    % Update GUI
                    set(app.customOut,'BackgroundColor',[.47 .67 .19]);
                catch
                end
                guidata(hObject, handles);
        end

        % Button pushed function: customRun
        function customRun_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Button pushed function: customSet
        function customSet_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Selection changed function: filterGroup
        function filterGroup_SelectionChangedFcn(app, event)
                handles = guidata(hObject);
            
                set(app.filterGroup,'SelectionChangeFcn',@filterGroup_SelectionChangedFcn)
                switch(get(eventdata.NewValue,'Tag'))
                    case 'FGfamily'
                        app.FGfilter = "family";
                    case 'FGgenera'
                        app.FGfilter = "genera";
                    case 'FGspecies'
                        app.FGfilter = "species";
                end
                guidata(hObject,handles);
        end

        % Value changed function: imfillMasksCB
        function imfillMasksCB_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Value changed function: inputSaveFreq
        function inputSaveFreq_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject, handles);
        end

        % Value changed function: quality
        function quality_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Value changed function: saveFolderSuffix
        function saveFolderSuffix_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end

        % Selection changed function: uibuttongroup5
        function uibuttongroup5_SelectionChangedFcn(app, event)
                handles = guidata(hObject);
            
                set(app.uibuttongroup5,'SelectionChangeFcn',@uibuttongroup5_SelectionChangedFcn)
                switch(get(eventdata.NewValue,'Tag'))
                    case 'Local'
                        app.radiobuttonLocal = 0.1;
                    case 'URL'
                        app.radiobuttonURL = 1;
                end
            
                guidata(hObject,handles);
        end

        % Value changed function: urlColumn
        function urlColumn_Callback(app, event)
                handles = guidata(hObject);
                guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'LeafMachineBatchGUI';
            app.axes1 = uiaxes(app.figure1);
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = '3) LeafMachine Output Directory';
            app.saveFolderSuffix = uieditfield(app.uibuttongroup1, 'text');
            app.saveFolderSuffix.ValueChangedFcn = createCallbackFcn(app, @saveFolderSuffix_Callback, true);
            app.text6 = uilabel(app.uibuttongroup1);
            app.text6.Text = '(Optional) Add New Folder in Directory';
            app.changeBatchSave = uibutton(app.uibuttongroup1, 'push');
            app.changeBatchSave.ButtonPushedFcn = createCallbackFcn(app, @changeBatchSave_Callback, true);
            app.changeBatchSave.Text = 'Select Directory';
            app.saveLocationText = uilabel(app.uibuttongroup1);
            app.saveLocationText.Text = 'Location not set...';
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.uibuttongroup3.Title = '2B) Process Darwin Core Images from a Local Folder';
            app.changeBatchOpen = uibutton(app.uibuttongroup3, 'push');
            app.changeBatchOpen.ButtonPushedFcn = createCallbackFcn(app, @changeBatchOpen_Callback, true);
            app.changeBatchOpen.Text = 'Select Directory';
            app.openLocationText = uilabel(app.uibuttongroup3);
            app.openLocationText.Text = 'Location not set...';
            app.Run = uibutton(app.figure1, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @Run_Callback, true);
            app.Run.Text = 'Run';
            app.Stop = uibutton(app.figure1, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @Stop_Callback, true);
            app.Stop.Text = 'Close GUI';
            app.fileSaveDisp = uilabel(app.figure1);
            app.fileSaveDisp.Text = '';
            app.progress = uilabel(app.figure1);
            app.progress.Text = '';
            app.uibuttongroup5 = uibuttongroup(app.figure1);
            app.uibuttongroup5.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup5_SelectionChangedFcn, true);
            app.uibuttongroup5.Title = 'Local or URL';
            app.uibuttongroup5.Visible = 'off';
            app.radiobuttonLocal = uiradiobutton(app.uibuttongroup5);
            app.radiobuttonLocal.Text = 'Local';
            app.radiobuttonLocal.Value = true;
            app.radiobuttonURL = uiradiobutton(app.uibuttongroup5);
            app.radiobuttonURL.Text = 'URL';
            app.text13 = uilabel(app.figure1);
            app.text13.Text = {'To force quit at any time, in the'; 'Command Window type: Ctrl + C'};
            app.progress2 = uilabel(app.figure1);
            app.progress2.Text = '';
            app.uibuttongroup4 = uibuttongroup(app.figure1);
            app.uibuttongroup4.Title = '2A) Process Darwin Core Images from URL ';
            app.urlColumn = uieditfield(app.uibuttongroup4, 'text');
            app.urlColumn.ValueChangedFcn = createCallbackFcn(app, @urlColumn_Callback, true);
            app.urlColumn.Tooltip = 'If the URLs are stored in DwC format, then use the ''DwC Resolution'' buttons to select whether to use high or low quality images.';
            app.text10 = uilabel(app.uibuttongroup4);
            app.text10.Text = 'Column Name in Spreadsheet';
            app.changeURL = uibutton(app.uibuttongroup4, 'push');
            app.changeURL.ButtonPushedFcn = createCallbackFcn(app, @changeURL_Callback, true);
            app.changeURL.Text = 'Choose File';
            app.urlText = uilabel(app.uibuttongroup4);
            app.urlText.Text = 'Darwin Core "images.csv" file not set...';
            app.changeURL2 = uibutton(app.uibuttongroup4, 'push');
            app.changeURL2.ButtonPushedFcn = createCallbackFcn(app, @changeURL2_Callback, true);
            app.changeURL2.Text = 'Choose File';
            app.urlText2 = uilabel(app.uibuttongroup4);
            app.urlText2.Text = 'Darwin Core "occurances.csv" file not set...';
            app.uibuttongroup6 = uibuttongroup(app.uibuttongroup4);
            app.uibuttongroup6.Title = 'DwC Resolution ';
            app.dwc_high = uiradiobutton(app.uibuttongroup6);
            app.dwc_high.Text = 'High';
            app.dwc_high.Value = true;
            app.dwc_low = uiradiobutton(app.uibuttongroup6);
            app.dwc_low.Text = 'Low';
            app.text15 = uilabel(app.uibuttongroup4);
            app.text15.Text = 'OR';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '4) Batch Processing Options';
            app.checkboxGPU = uicheckbox(app.uipanel1);
            app.checkboxGPU.Text = 'Use GPU';
            app.allFilesSuffix = uieditfield(app.uipanel1, 'text');
            app.allFilesSuffix.ValueChangedFcn = createCallbackFcn(app, @allFilesSuffix_Callback, true);
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = '(Optional) Add suffix to all output files:';
            app.quality = uicheckbox(app.uipanel1);
            app.quality.ValueChangedFcn = createCallbackFcn(app, @quality_Callback, true);
            app.quality.Text = 'Save high quality images';
            app.checkboxLCM = uicheckbox(app.uipanel1);
            app.checkboxLCM.Text = 'Save all leaf candidate masks';
            app.checkboxLS = uicheckbox(app.uipanel1);
            app.checkboxLS.Enable = 'off';
            app.checkboxLS.Text = 'Process partial/clump leaves using Lazy-Snapping';
            app.checkboxIND = uicheckbox(app.uipanel1);
            app.checkboxIND.Text = 'Save individual image data files';
            app.inputSaveFreq = uieditfield(app.uipanel1, 'text');
            app.inputSaveFreq.ValueChangedFcn = createCallbackFcn(app, @inputSaveFreq_Callback, true);
            app.inputSaveFreq.Value = '50';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Text = 'Temp Save Frequency';
            app.imfillMasksCB = uicheckbox(app.uipanel1);
            app.imfillMasksCB.ValueChangedFcn = createCallbackFcn(app, @imfillMasksCB_Callback, true);
            app.imfillMasksCB.Text = 'Fill in holes in ''Leaf'' masks';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = '1) Setup Tools';
            app.uipanel2 = uipanel(app.uipanel4);
            app.uipanel2.Title = 'Download Darwin Core Images to Local Computer';
            app.text24 = uilabel(app.uipanel2);
            app.text24.Text = 'Select an "images.csv" file and an "occurrences.csv" file to download images locally';
            app.Dimages = uibutton(app.uipanel2, 'push');
            app.Dimages.ButtonPushedFcn = createCallbackFcn(app, @Dimages_Callback, true);
            app.Dimages.Text = 'Choose "images.csv" File';
            app.Docc = uibutton(app.uipanel2, 'push');
            app.Docc.ButtonPushedFcn = createCallbackFcn(app, @Docc_Callback, true);
            app.Docc.Text = 'Choose "occurrences.csv" File';
            app.Drun = uibutton(app.uipanel2, 'push');
            app.Drun.ButtonPushedFcn = createCallbackFcn(app, @Drun_Callback, true);
            app.Drun.Text = 'Download';
            app.uipanel8 = uipanel(app.uipanel4);
            app.uipanel8.Title = 'Set Family/Genera/Species Filter';
            app.filterGroup = uibuttongroup(app.uipanel8);
            app.filterGroup.SelectionChangedFcn = createCallbackFcn(app, @filterGroup_SelectionChangedFcn, true);
            app.filterGroup.Title = 'Filter Type';
            app.FGfamily = uiradiobutton(app.filterGroup);
            app.FGfamily.Text = 'Family';
            app.FGfamily.Value = true;
            app.FGgenera = uiradiobutton(app.filterGroup);
            app.FGgenera.Text = 'Genera';
            app.FGspecies = uiradiobutton(app.filterGroup);
            app.FGspecies.Text = 'Species';
            app.text23 = uilabel(app.uipanel8);
            app.text23.Text = 'Single column .csv file containing chosen families, genera, or species';
            app.FGset = uibutton(app.uipanel8, 'push');
            app.FGset.ButtonPushedFcn = createCallbackFcn(app, @FGset_Callback, true);
            app.FGset.Text = 'Choose File';
            app.uipanel6 = uipanel(app.uipanel4);
            app.uipanel6.Title = 'Generate Custom "images.csv" File ';
            app.customSet = uibutton(app.uipanel6, 'push');
            app.customSet.ButtonPushedFcn = createCallbackFcn(app, @customSet_Callback, true);
            app.customSet.Text = 'Choose File';
            app.customOut = uibutton(app.uipanel6, 'push');
            app.customOut.ButtonPushedFcn = createCallbackFcn(app, @customOut_Callback, true);
            app.customOut.Text = 'Select Output Directory';
            app.text22 = uilabel(app.uipanel6);
            app.text22.Text = 'Single column .csv file containing chosen coreID numbers from an "occurences.csv" file';
            app.customRun = uibutton(app.uipanel6, 'push');
            app.customRun.ButtonPushedFcn = createCallbackFcn(app, @customRun_Callback, true);
            app.customRun.Text = 'Generate';
            app.text25 = uilabel(app.figure1);
            app.text25.Text = 'OR';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeafMachineBatchGUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @LeafMachineBatchGUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end